classdef learningquiz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SubmitButton               matlab.ui.control.Button
        Switch_2                   matlab.ui.control.Switch
        Switch_2Label              matlab.ui.control.Label
        Switch_4                   matlab.ui.control.Switch
        Switch_4Label              matlab.ui.control.Label
        Switch_5                   matlab.ui.control.Switch
        Switch_5Label              matlab.ui.control.Label
        Switch_3                   matlab.ui.control.Switch
        Switch_3Label              matlab.ui.control.Label
        Switch_6                   matlab.ui.control.Switch
        Switch_6Label              matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        SwitchLabel                matlab.ui.control.Label
        Slider                     matlab.ui.control.Slider
        SliderLabel                matlab.ui.control.Label
        Image4                     matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        PlayAudioButton            matlab.ui.control.Button
        SubmitRatingButton         matlab.ui.control.Button
        ResultsPanel               matlab.ui.container.Panel
        KinestheticImage           matlab.ui.control.Image
        VisualImage                matlab.ui.control.Image
        AuditoryImage              matlab.ui.control.Image
        KinestheticDescription     matlab.ui.control.Label
        VisualDescription          matlab.ui.control.Label
        AuditoryDescription        matlab.ui.control.Label
        LearningStyleResultsLabel  matlab.ui.control.Label
        KinestheticAxis            matlab.ui.control.UIAxes
        VisualAxis                 matlab.ui.control.UIAxes
        AuditoryAxis               matlab.ui.control.UIAxes
        SkipButton                 matlab.ui.control.Button
        OptionButton3              matlab.ui.control.Button
        OptionButton2              matlab.ui.control.Button
        QuestionLabel              matlab.ui.control.Label
        OptionButton1              matlab.ui.control.Button
    end

    
    properties (Access = private)
        CurrentQuestionIndex = 1
        Scores = struct('Visual', 0, 'Auditory', 0, 'Kinesthetic', 0)
        QuizData
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Define quiz questions and options
           app.QuizData(1).question = "How do you best remember information?";
           app.QuizData(1).options = {"By seeing images", "By hearing explanations", "By doing activities"};
           app.QuizData(1).type = {"Visual", "Auditory", "Kinesthetic"};
           app.QuizData(1).questionType = "multiple choice";
      
           app.QuizData(2).question = "What helps you concentrate?";
           app.QuizData(2).options = {"Reading notes", "Listening to music", "Taking walks"};
           app.QuizData(2).type = {"Visual", "Auditory", "Kinesthetic"};
           app.QuizData(2).questionType = "multiple choice";

           app.QuizData(3).question = "What's your ideal study tool?";
           app.QuizData(3).options = {"Flashcards", "Audio recordings", "Hands-on tools"};
           app.QuizData(3).type = {"Visual", "Auditory", "Kinesthetic"};
           app.QuizData(3).questionType = "basic multiple choice";
           
          app.QuizData(4).question = "I find diagrams/images helpful when learning (1 = low, 5 = high)";
          app.QuizData(4).type = "Visual";
          app.QuizData(4).questionType = "slider";

          app.QuizData(5).question = "Rewriting notes is the best way to memorize content (1 = low, 5 = high)";
          app.QuizData(5).type = "Kinesthetic";
          app.QuizData(5).questionType = "slider";

          app.QuizData(6).question = "I replay lectures to help me understand new concepts (1 = low, 5 = high)";
          app.QuizData(6).type = "Auditory";
          app.QuizData(6).questionType = "slider";

          app.QuizData(7).question = "Follow the instructions in the audio.";
          app.QuizData(7).options = {"1", "3", "7"};
          app.QuizData(7).type = {"Visual", "Auditory", "Kinesthetic"};
          app.QuizData(7).questionType = "audio";

          app.QuizData(8).question = "Which environment, as pictured, would you most prefer to learn in?";
          app.QuizData(8).options = {"Coffee Shop", "Outdoors", "Colorful Lounge"};
          app.QuizData(8).type = {"Auditory", "Kinesthetic", "Visual"};
          app.QuizData(8).questionType = "image-based multiple choice";

          app.QuizData(9).question = "";
          app.QuizData(9).questionType = "switches";
          app.QuizData(9).SwitchLabels = {"a podcast or verbal explanation", "a diagram or infographic"; "watch demonstrations or videos", "try it myself with hands on practice"; "someone explaining them step by step", "jumping in and figuring it out as I go"; "repeating it aloud or listening to recordings", "writing it down or using flashcards"; "highlighters and mind maps", "fidget tools or pacing around"; "hear the speaker's voice in my head", "remember the physical actions I performed"};
          app.QuizData(9).SwitchTypes = {"Auditory", "Visual"; "Visual", "Kinesthetic"; "Auditory", "Kinesthetic"; "Auditory", "Visual"; "Visual", "Kinesthetic"; "Auditory", "Kinesthetic"};


           % Initialize score tracking
           app.Scores.Visual = 0;
           app.Scores.Auditory = 0;
           app.Scores.Kinesthetic = 0;
      
           app.CurrentQuestionIndex = 1;
      
           % Display first question
           displayQuestion(app);
        end

       
        function updateScores(app, selectedIndex)
            type = app.QuizData(app.CurrentQuestionIndex).type{selectedIndex};
            app.Scores.(type) = app.Scores.(type) + 1;
            
            app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;            
            if app.CurrentQuestionIndex > length(app.QuizData)
                showResults(app);
            else
                displayQuestion(app);
            end
        end


        function updateSliderScore(app)
            q = app.QuizData(app.CurrentQuestionIndex);
            sliderValue = round(app.Slider.Value);
            app.Scores.(q.type) = app.Scores.(q.type) + sliderValue;
            
            app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;
            
            if app.CurrentQuestionIndex > length(app.QuizData)
                showResults(app);
            else
              displayQuestion(app);
            end
        end

         function processSwitchSelections(app)
            q = app.QuizData(app.CurrentQuestionIndex);
            % get state of each switch and update scores
            AVswitches = {app.Switch, app.Switch_4};
            for i = 1:length(AVswitches)
                % go through both switches and evaluate their positions
                AVcurrent_Switch = AVswitches{i};
                if strcmp(AVcurrent_Switch.Value, AVcurrent_Switch.Items{1})
                    app.Scores.Auditory = app.Scores.Auditory + 1;
                else
                    app.Scores.Visual = app.Scores.Visual + 1;
                end
            end
            VKswitches = {app.Switch_2, app.Switch_5};
            for i = 1:length(VKswitches)
                % go through both switches and evaluate their positions
                VKcurrent_Switch = VKswitches{i};
                if strcmp(VKcurrent_Switch.Value, VKcurrent_Switch.Items{1})
                    app.Scores.Visual = app.Scores.Visual + 1;
                else
                    app.Scores.Kinesthetic = app.Scores.Kinesthetic + 1;
                end
            end
            AKswitches = {app.Switch_3, app.Switch_6};
            for i = 1:length(AKswitches)
                % go through both switches and evaluate their positions
                AKcurrent_Switch = AKswitches{i};
                if strcmp(AKcurrent_Switch.Value, AKcurrent_Switch.Items{1})
                    app.Scores.Auditory = app.Scores.Auditory + 1;
                else
                    app.Scores.Kinesthetic = app.Scores.Kinesthetic + 1;
                end
            end


        end

        % Button pushed function: OptionButton1
        function OptionButton1Pushed(app, event)
             updateScores(app, 1);

        end

        % Button pushed function: OptionButton2
        function OptionButton2Pushed(app, event)
            updateScores(app, 2);

        end

        % Button pushed function: OptionButton3
        function OptionButton3Pushed(app, event)
            updateScores(app, 3);
        end
       
        function displayQuestion(app)
            q = app.QuizData(app.CurrentQuestionIndex);

            if strcmp(q.questionType, "slider")
                app.OptionButton1.Visible = 'off';
                app.OptionButton2.Visible = 'off';
                app.OptionButton3.Visible = 'off';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'on';
                app.SliderLabel.Visible = 'on';
                app.SubmitRatingButton.Visible = 'on';
                app.PlayAudioButton.Visible = "off";
                app.Switch.Visible = 'off';
                app.Switch_6.Visible = 'off';
                app.Switch_3.Visible = 'off';
                app.Switch_5.Visible = 'off';
                app.Switch_4.Visible = 'off';
                app.Switch_2.Visible = 'off';
                app.SwitchLabel.Visible = 'off';
                app.Switch_6Label.Visible = 'off';
                app.Switch_3Label.Visible = 'off';
                app.Switch_5Label.Visible = 'off';
                app.Switch_4Label.Visible = 'off';
                app.Switch_2Label.Visible = 'off';
                app.SubmitButton.Visible = 'off';

                app.QuestionLabel.Text = q.question;

                app.Slider.Value = 3; % Default value

            elseif strcmp(q.questionType, "audio")
               app.OptionButton1.Visible = 'on';
                app.OptionButton2.Visible = 'on';
                app.OptionButton3.Visible = 'on';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.PlayAudioButton.Visible = "on";
                app.Image2.Visible = 'off';
                app.Image3.Visible = 'off';
                app.Image4.Visible = 'off';
                app.Switch.Visible = 'off';
                app.Switch_6.Visible = 'off';
                app.Switch_3.Visible = 'off';
                app.Switch_5.Visible = 'off';
                app.Switch_4.Visible = 'off';
                app.Switch_2.Visible = 'off';
                app.SwitchLabel.Visible = 'off';
                app.Switch_6Label.Visible = 'off';
                app.Switch_3Label.Visible = 'off';
                app.Switch_5Label.Visible = 'off';
                app.Switch_4Label.Visible = 'off';
                app.Switch_2Label.Visible = 'off';
                app.SubmitButton.Visible = 'off';

               app.QuestionLabel.Text = q.question;
               app.OptionButton1.Text = q.options{1};
               app.OptionButton2.Text = q.options{2};
               app.OptionButton3.Text = q.options{3};


            elseif strcmp (q.questionType, "multiple choice")
                % Multiple-choice question
                app.OptionButton1.Visible = 'on';
                app.OptionButton2.Visible = 'on';
                app.OptionButton3.Visible = 'on';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.PlayAudioButton.Visible = "off";
                app.Image2.Visible = 'off';
                app.Image3.Visible = 'off';
                app.Image4.Visible = 'off';
                app.Switch.Visible = 'off';
                app.Switch_6.Visible = 'off';
                app.Switch_3.Visible = 'off';
                app.Switch_5.Visible = 'off';
                app.Switch_4.Visible = 'off';
                app.Switch_2.Visible = 'off';
                app.SwitchLabel.Visible = 'off';
                app.Switch_6Label.Visible = 'off';
                app.Switch_3Label.Visible = 'off';
                app.Switch_5Label.Visible = 'off';
                app.Switch_4Label.Visible = 'off';
                app.Switch_2Label.Visible = 'off';
                app.SubmitButton.Visible = 'off';

                app.QuestionLabel.Text = q.question;
                app.OptionButton1.Text = q.options{1};
                app.OptionButton2.Text = q.options{2};
                app.OptionButton3.Text = q.options{3};


            elseif strcmp (q.questionType, "image-based multiple choice")
                % Multiple-choice question with images
                app.OptionButton1.Visible = 'on';
                app.OptionButton2.Visible = 'on';
                app.OptionButton3.Visible = 'on';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.PlayAudioButton.Visible = 'off';
                app.Image2.Visible = 'on';
                app.Image3.Visible = 'on';
                app.Image4.Visible = 'on';
                app.Switch.Visible = 'off';
                app.Switch_6.Visible = 'off';
                app.Switch_3.Visible = 'off';
                app.Switch_5.Visible = 'off';
                app.Switch_4.Visible = 'off';
                app.Switch_2.Visible = 'off';
                app.SwitchLabel.Visible = 'off';
                app.Switch_6Label.Visible = 'off';
                app.Switch_3Label.Visible = 'off';
                app.Switch_5Label.Visible = 'off';
                app.Switch_4Label.Visible = 'off';
                app.Switch_2Label.Visible = 'off';
                app.SubmitButton.Visible = 'off';

                app.QuestionLabel.Text = q.question;
                app.OptionButton1.Text = q.options{1};
                app.OptionButton2.Text = q.options{2};
                app.OptionButton3.Text = q.options{3};


            elseif strcmp (q.questionType, "switches")
                % Switch questions
                app.OptionButton1.Visible = 'off';
                app.OptionButton2.Visible = 'off';
                app.OptionButton3.Visible = 'off';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.PlayAudioButton.Visible = 'off';
                app.Image2.Visible = 'off';
                app.Image3.Visible = 'off';
                app.Image4.Visible = 'off';
                app.Switch.Visible = 'on';
                app.Switch_6.Visible = 'on';
                app.Switch_3.Visible = 'on';
                app.Switch_5.Visible = 'on';
                app.Switch_4.Visible = 'on';
                app.Switch_2.Visible = 'on';
                app.SwitchLabel.Visible = 'on';
                app.Switch_6Label.Visible = 'on';
                app.Switch_3Label.Visible = 'on';
                app.Switch_5Label.Visible = 'on';
                app.Switch_4Label.Visible = 'on';
                app.Switch_2Label.Visible = 'on';
                app.QuestionLabel.Visible = 'off';
                app.SubmitButton.Visible = 'on';


            end
       

        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
           
           % skip to next question
           app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;
           if app.CurrentQuestionIndex > length(app.QuizData)
               showResults(app);
           else
               displayQuestion(app);
               app.ButtonGroup.SelectedObject = []; % clear selection
           end
        end

        function showResults(app)
            app.QuestionLabel.Visible = 'off';
            app.OptionButton1.Visible = 'off';
            app.OptionButton2.Visible = 'off';
            app.OptionButton3.Visible = 'off';
            app.SkipButton.Visible = 'off';
            app.Slider.Visible = 'off';         
            app.SliderLabel.Visible = 'off';     
            app.SubmitRatingButton.Visible = 'off';
            app.PlayAudioButton.Visible = "off";
            app.ResultsPanel.Visible = 'on';
            app.Image2.Visible = 'off';
            app.Image3.Visible = 'off';
            app.Image4.Visible = 'off';
            app.Switch.Visible = 'off';
            app.Switch_6.Visible = 'off';
            app.Switch_3.Visible = 'off';
            app.Switch_5.Visible = 'off';
            app.Switch_4.Visible = 'off';
            app.Switch_2.Visible = 'off';
            app.SwitchLabel.Visible = 'off';
            app.Switch_6Label.Visible = 'off';
            app.Switch_3Label.Visible = 'off';
            app.Switch_5Label.Visible = 'off';
            app.Switch_4Label.Visible = 'off';
            app.Switch_2Label.Visible = 'off';
            app.SubmitButton.Visible = 'off';
            app.AuditoryAxis.Visible = 'off';
            app.AuditoryImage.Visible = 'off';
            app.VisualAxis.Visible = 'off';
            app.VisualImage.Visible = 'off';
            app.KinestheticAxis.Visible = 'off';
            app.KinestheticImage.Visible = 'off';
            
            types = fieldnames(app.Scores);
            values = structfun(@(x) x, app.Scores);
            [~, idx] = max(values);
            maxType = types{idx};

            app.LearningStyleResultsLabel.Text = ['Congratulations! You are a ' maxType ' learner!'];

            app.AuditoryDescription.Text = ' ';
            app.VisualDescription.Text = ' ';
            app.KinestheticDescription.Text = ' ';

            if strcmp(maxType, 'Auditory')
                app.AuditoryDescription.Text = 'As an Auditory learner, you absorb information best through listening. Highly effective study methods for you include reviewing lectures, participating in discussions, listening to podcasts, and even reading aloud. You find that verbal explanations and storytelling help you to understand and retain concepts. To maximize your learning, consider incorporating more audio-based strategies into your study habits.';
                app.AuditoryAxis.Visible = 'on';
                app.AuditoryImage.Visible = 'on';
            end
            if strcmp(maxType, 'Visual')
                app.VisualDescription.Text = 'As a Visual learner, you absorb information best by seeing and reviewing information. Written notes, diagrams, organized layouts, and graphs help you absorb material in a spatial and easily comprehensible manner. You likely benefit from rereading notes, studying charts, and using color-coded systems to organize your thoughts. To maximize your learning, consider breaking down complex information into visual formats.';
                app.VisualAxis.Visible = 'on';
                app.VisualImage.Visible = 'on';
            end
            if strcmp(maxType, 'Kinesthetic')
                app.KinestheticDescription.Text = 'As a Kinesthetic learner, you absorb information best through hands-on experience and physical movement. Some important strategies for strengthening your understanding and memory include building models, using gestures, or being active while studying. Compared to traditional lecture-style learning, you find truly immersing yourself in the material is the most effective way of learning it. To maximize your learning, consider finding ways to connect movement with studying to make learning feel more natural.';
                app.KinestheticAxis.Visible = 'on';
                app.KinestheticImage.Visible = 'on';
            end
            
        end

        % Button pushed function: SubmitRatingButton
        function SubmitRatingButtonPushed(app, event)
            updateSliderScore(app);


        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            q = app.QuizData(app.CurrentQuestionIndex);
            [y, Fs] = audioread("sample audio for matlab.wav");
               sound(y, Fs)

        end

        % Callback function: not associated with a component
        function SwitchValueChanged(app, event)
            
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
           processSwitchSelections(app);
           app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;
           if app.CurrentQuestionIndex > length(app.QuizData)
               showResults(app);
           else
               displayQuestion(app);
               app.ButtonGroup.SelectedObject = []; % clear selection
               processSwitchSelections(app);

           end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionButton1 = uibutton(app.UIFigure, 'push');
            app.OptionButton1.ButtonPushedFcn = createCallbackFcn(app, @OptionButton1Pushed, true);
            app.OptionButton1.Text = 'Option Button 1';
            app.QuestionLabel = uilabel(app.UIFigure);
            app.QuestionLabel.Text = 'Question Label';
            app.OptionButton2 = uibutton(app.UIFigure, 'push');
            app.OptionButton2.ButtonPushedFcn = createCallbackFcn(app, @OptionButton2Pushed, true);
            app.OptionButton2.Text = 'Option Button 2';
            app.OptionButton3 = uibutton(app.UIFigure, 'push');
            app.OptionButton3.ButtonPushedFcn = createCallbackFcn(app, @OptionButton3Pushed, true);
            app.OptionButton3.Text = 'Option Button 3';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPanel.Visible = 'off';
            app.AuditoryAxis = uiaxes(app.ResultsPanel);
            title(app.AuditoryAxis, 'Title')
            xlabel(app.AuditoryAxis, 'X')
            ylabel(app.AuditoryAxis, 'Y')
            zlabel(app.AuditoryAxis, 'Z')
            app.VisualAxis = uiaxes(app.ResultsPanel);
            title(app.VisualAxis, 'Title')
            xlabel(app.VisualAxis, 'X')
            ylabel(app.VisualAxis, 'Y')
            zlabel(app.VisualAxis, 'Z')
            app.KinestheticAxis = uiaxes(app.ResultsPanel);
            title(app.KinestheticAxis, 'Title')
            xlabel(app.KinestheticAxis, 'X')
            ylabel(app.KinestheticAxis, 'Y')
            zlabel(app.KinestheticAxis, 'Z')
            app.LearningStyleResultsLabel = uilabel(app.ResultsPanel);
            app.LearningStyleResultsLabel.Text = 'Learning Style Results';
            app.AuditoryDescription = uilabel(app.ResultsPanel);
            app.AuditoryDescription.Text = 'This is a description for auditory learners!';
            app.VisualDescription = uilabel(app.ResultsPanel);
            app.VisualDescription.Text = 'This is a description for visual learners!';
            app.KinestheticDescription = uilabel(app.ResultsPanel);
            app.KinestheticDescription.Text = 'This is a description for kinesthetic learners!';
            app.AuditoryImage = uiimage(app.ResultsPanel);
            app.VisualImage = uiimage(app.ResultsPanel);
            app.KinestheticImage = uiimage(app.ResultsPanel);
            app.SubmitRatingButton = uibutton(app.UIFigure, 'push');
            app.SubmitRatingButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitRatingButtonPushed, true);
            app.SubmitRatingButton.Text = 'Submit Rating';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'coffee shop.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'outdoor.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'colorful.jpg');
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 5];
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = '"When learning a new concept, I prefer..."';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'a podcast or verbal explanation', 'a diagram or infographic'};
            app.Switch.Value = 'a podcast or verbal explanation';
            app.Switch_6Label = uilabel(app.UIFigure);
            app.Switch_6Label.Text = '"When recalling information, I..."';
            app.Switch_6 = uiswitch(app.UIFigure, 'slider');
            app.Switch_6.Items = {'hear the speaker''s voice in my head', 'remember the physical actions I performed'};
            app.Switch_6.Value = 'hear the speaker''s voice in my head';
            app.Switch_3Label = uilabel(app.UIFigure);
            app.Switch_3Label.Text = '"To understand instructions, I prefer..."';
            app.Switch_3 = uiswitch(app.UIFigure, 'slider');
            app.Switch_3.Items = {'someone explaining them step by step', 'jumping in and figuring it out as I go'};
            app.Switch_3.Value = 'someone explaining them step by step';
            app.Switch_5Label = uilabel(app.UIFigure);
            app.Switch_5Label.Text = '"When studying, I focus better with..."';
            app.Switch_5 = uiswitch(app.UIFigure, 'slider');
            app.Switch_5.Items = {'highlighters and mind maps', 'fidget tools or pacing around'};
            app.Switch_5.Value = 'highlighters and mind maps';
            app.Switch_4Label = uilabel(app.UIFigure);
            app.Switch_4Label.Text = '"To memorize information, I rely on..."';
            app.Switch_4 = uiswitch(app.UIFigure, 'slider');
            app.Switch_4.Items = {'repeating it aloud or listening to recordings', 'writing it down or using flashcards'};
            app.Switch_4.Value = 'repeating it aloud or listening to recordings';
            app.Switch_2Label = uilabel(app.UIFigure);
            app.Switch_2Label.Text = '"I learn best when I..."';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'watch demonstrations or videos', 'try it myself with hands on practice'};
            app.Switch_2.Value = 'watch demonstrations or videos';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = learningquiz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end